classdef WYE_DELTA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        VanEditFieldLabel            matlab.ui.control.Label
        VanEditField                 matlab.ui.control.EditField
        VbnEditFieldLabel            matlab.ui.control.Label
        VbnEditField                 matlab.ui.control.EditField
        VcnEditFieldLabel            matlab.ui.control.Label
        VcnEditField                 matlab.ui.control.EditField
        Line1EditFieldLabel          matlab.ui.control.Label
        Line1EditField               matlab.ui.control.EditField
        Line2EditFieldLabel          matlab.ui.control.Label
        Line2EditField               matlab.ui.control.EditField
        Line3EditFieldLabel          matlab.ui.control.Label
        Line3EditField               matlab.ui.control.EditField
        Load1EditFieldLabel          matlab.ui.control.Label
        Load1EditField               matlab.ui.control.EditField
        Load2EditFieldLabel          matlab.ui.control.Label
        Load2EditField               matlab.ui.control.EditField
        Load3EditFieldLabel          matlab.ui.control.Label
        Load3EditField               matlab.ui.control.EditField
        OutputPanel                  matlab.ui.container.Panel
        LineCurrentsALabel           matlab.ui.control.Label
        PhaseCurrentsALabel          matlab.ui.control.Label
        LineVoltagesVLabel           matlab.ui.control.Label
        PhaseVoltagesVLabel          matlab.ui.control.Label
        TotalRealPowerLabel          matlab.ui.control.Label
        TotalRealPowerEditField      matlab.ui.control.NumericEditField
        TotalReactivePowerLabel      matlab.ui.control.Label
        TotalReactivePowerEditField  matlab.ui.control.NumericEditField
        PowerFactorLabel             matlab.ui.control.Label
        PowerFactorEditField         matlab.ui.control.NumericEditField
        WLabel                       matlab.ui.control.Label
        VARLabel                     matlab.ui.control.Label
        VALabel                      matlab.ui.control.Label
        I_aAEditFieldLabel           matlab.ui.control.Label
        I_aAEditField                matlab.ui.control.EditField
        I_bBEditFieldLabel           matlab.ui.control.Label
        I_bBEditField                matlab.ui.control.EditField
        I_cCEditFieldLabel           matlab.ui.control.Label
        I_cCEditField                matlab.ui.control.EditField
        I_ABEditFieldLabel           matlab.ui.control.Label
        I_ABEditField                matlab.ui.control.EditField
        I_BCEditFieldLabel           matlab.ui.control.Label
        I_BCEditField                matlab.ui.control.EditField
        I_CAEditFieldLabel           matlab.ui.control.Label
        I_CAEditField                matlab.ui.control.EditField
        V_ABEditField_3Label         matlab.ui.control.Label
        V_ABEditField_3              matlab.ui.control.EditField
        V_BCEditField_3Label         matlab.ui.control.Label
        V_BCEditField_3              matlab.ui.control.EditField
        V_CAEditField_3Label         matlab.ui.control.Label
        V_CAEditField_3              matlab.ui.control.EditField
        V_ABEditField_4Label         matlab.ui.control.Label
        V_ABEditField_4              matlab.ui.control.EditField
        V_BCEditField_4Label         matlab.ui.control.Label
        V_BCEditField_4              matlab.ui.control.EditField
        V_CAEditField_4Label         matlab.ui.control.Label
        V_CAEditField_4              matlab.ui.control.EditField
        LeadorlagEditField           matlab.ui.control.EditField
        AtLoadsEndLabel              matlab.ui.control.Label
        TotalComplexPowerEditFieldLabel  matlab.ui.control.Label
        TotalComplexPowerEditField   matlab.ui.control.EditField
        ALabel                       matlab.ui.control.Label
        BLabel                       matlab.ui.control.Label
        CLabel                       matlab.ui.control.Label
        bLabel                       matlab.ui.control.Label
        aLabel                       matlab.ui.control.Label
        cLabel                       matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        GiveinputsinthefigurebelowinaibformatLabel  matlab.ui.control.Label
        SIMULATEButton               matlab.ui.control.Button
        BACKButton                   matlab.ui.control.Button
        DropDown                     matlab.ui.control.DropDown
        PlotButton                   matlab.ui.control.Button
        FrequencyEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        nLabel                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        V1,V2,V3 %input voltage sources
        ZL1;ZL2;ZL3; %line inpedances
        Z1;Z2;Z3; %load impedances
        VP1,VP2,VP3,VL1,VL2,VL3,IL1,IL2,IL3,IP1,IP2,IP3;
        freq;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: VanEditField
        function VanEditFieldValueChanged(app, event)
            value = app.VanEditField.Value;
            app.V1=str2double(value);
        end

        % Value changed function: VbnEditField
        function VbnEditFieldValueChanged(app, event)
            value = app.VbnEditField.Value;
            app.V2=str2double(value);
        end

        % Value changed function: VcnEditField
        function VcnEditFieldValueChanged(app, event)
            value = app.VcnEditField.Value;
            app.V3=str2double(value);
        end

        % Value changed function: Line1EditField
        function Line1EditFieldValueChanged(app, event)
            value = app.Line1EditField.Value;
            app.ZL1=str2double(value);
        end

        % Value changed function: Line2EditField
        function Line2EditFieldValueChanged(app, event)
            value = app.Line2EditField.Value;
            app.ZL2=str2double(value);
        end

        % Value changed function: Line3EditField
        function Line3EditFieldValueChanged(app, event)
            value = app.Line3EditField.Value;
            app.ZL3=str2double(value);
        end

        % Value changed function: Load1EditField
        function Load1EditFieldValueChanged(app, event)
            value = app.Load1EditField.Value;
            app.Z1=str2double(value);
        end

        % Value changed function: Load2EditField
        function Load2EditFieldValueChanged(app, event)
            value = app.Load2EditField.Value;
            app.Z2=str2double(value);
        end

        % Value changed function: Load3EditField
        function Load3EditFieldValueChanged(app, event)
            value = app.Load3EditField.Value;
            app.Z3=str2double(value);
        end

        % Button pushed function: SIMULATEButton
        function SIMULATEButtonPushed(app, event)
            a=[app.ZL1+app.Z1+app.ZL2 -app.Z1 -app.ZL2;-app.Z1 app.Z1+app.Z2+app.Z3 -app.Z2;-app.ZL2 -app.Z2 app.Z2+app.ZL2+app.ZL3];
            b=[app.V1-app.V2;0;app.V2-app.V3];
            I=Gauss_jordan(a,b);
            
            %find line currents
            app.IL1=I(1);
            app.IL2=I(3) - I(1);
            app.IL3= -I(3);
            
            
            %find phase currents
            app.IP1= I(1)-I(2);
            app.IP2= I(3)-I(2);
            app.IP3= -I(2);
 
        
            %show all line currents 
            app.I_aAEditField.Value=num2str(app.IL1);
            app.I_bBEditField.Value=num2str( app.IL2);
            app.I_cCEditField.Value=num2str(app.IL3);
            
            %show all phase currents
            app.I_ABEditField.Value=num2str(app.IP1);
            app.I_BCEditField.Value=num2str( app.IP2);
            app.I_CAEditField.Value=num2str(app.IP3);
            
            %find all the phase voltages
            app.VP1=app.IP1 * app.Z1;
            app.VP2=app.IP2 * app.Z2;
            app.VP3=app.IP3 * app.Z3;
            
            %find all the line voltages at load side same as phase voltages
            app.VL1=app.IP1 * app.Z1;
            app.VL2=app.IP2 * app.Z2;
            app.VL3=app.IP3 * app.Z3;
            
             %show all the phase voltages now
            app.V_ABEditField_4.Value=num2str(app.VP1);
            app.V_BCEditField_4.Value=num2str(app.VP2);
            app.V_CAEditField_4.Value=num2str(app.VP3);
            
            %show all the line voltages at loads end now
            app.V_ABEditField_3.Value=num2str(app.VL1);
            app.V_BCEditField_3.Value=num2str(app.VL2);
            app.V_CAEditField_3.Value=num2str(app.VL3);
            
            %calculate total power 
            S1=abs(app.IP1)^2 * app.Z1;
            S2=abs(app.IP2)^2 * app.Z2;
            S3=abs(app.IP3)^2 * app.Z3;
            S=S1+S2+S3;
            
            %calculate total real power
            P=real(S);
            
            %calculate total apparent power
            Q=imag(S);
            
            %calculate pf
            phase=angle(S);
            pf=cos(phase);
            
            %determine whether leading or lagging or unity
            if Q<0
                app.LeadorlagEditField.Value="Leading";
            elseif Q>0 
                app.LeadorlagEditField.Value="Lagging";
            else
                app.LeadorlagEditField.Value="Unity pf";
            end
            
            %show complex power
            app.TotalComplexPowerEditField.Value=num2str(S);
            
            %show total reactive power
            app.TotalReactivePowerEditField.Value=Q;
            
            %show total real power
            app.TotalRealPowerEditField.Value=P;
            
            %show pf
            app.PowerFactorEditField.Value=pf;
            
            
        end

        % Value changed function: I_aAEditField
        function I_aAEditFieldValueChanged(app, event)
            value = app.I_aAEditField.Value;       
        end

        % Callback function
        function HOMEButtonPushed(app, event)
            app.delete
            HomePage
        end

        % Callback function
        function EXITButtonPushed(app, event)
            app.delete
            ending
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            app.delete
            combination
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            t=0:0.0005:5*(1./app.freq);
            f=app.freq;
            if isempty(f)
                warning_window
            else
            
                PV1=abs(app.VP1)*sin(2*pi*f.*t+angle(app.VP1)*(180/pi));
                PV2=abs(app.VP2)*sin(2*pi*f.*t+angle(app.VP2)*(180/pi));
                PV3=abs(app.VP3)*sin(2*pi*f.*t+angle(app.VP3)*(180/pi));
                LV1=abs(app.VL1)*sin(2*pi*f.*t+angle(app.VL1)*(180/pi));
                LV2=abs(app.VL2)*sin(2*pi*f.*t+angle(app.VL2)*(180/pi));
                LV3=abs(app.VL3)*sin(2*pi*f.*t+angle(app.VL3)*(180/pi));
                LI1=abs(app.IL1)*sin(2*pi*f.*t+angle(app.IL1)*(180/pi));
                LI2=abs(app.IL2)*sin(2*pi*f.*t+angle(app.IL2)*(180/pi));
                LI3=abs(app.IL3)*sin(2*pi*f.*t+angle(app.IL3)*(180/pi));
                PI1=abs(app.IP1)*sin(2*pi*f.*t+angle(app.IP1)*(180/pi));
                PI2=abs(app.IP2)*sin(2*pi*f.*t+angle(app.IP2)*(180/pi));
                PI3=abs(app.IP3)*sin(2*pi*f.*t+angle(app.IP3)*(180/pi));
                
                if strcmp(app.DropDown.Value,'Line currents')
                    
                    plot(t,LI1,'r-');
                    hold on
                    plot(t,LI2,'b-');
                    hold on
                    plot(t,LI3,'g-');
                    legend('IL1','IL2','IL3')
                    xlabel('time');
                    ylabel('Line currents');


                elseif strcmp(app.DropDown.Value,'Phase currents')
                    plot(t,PI1,'r-');
                    hold on
                    plot(t,PI2,'b-');
                    hold on
                    plot(t,PI3,'g-');
                    legend('IP1','IP2','IP3')
                    xlabel('time');
                    ylabel('Phase currents');
                    
                elseif strcmp(app.DropDown.Value,'Line voltages')
                    plot(t,LV1,'r-');
                    hold on
                    plot(t,LV2,'b-');
                    hold on
                    plot(t,LV3,'g-');
                    legend('VL1','VL2','VL3')
                    xlabel('time');
                    ylabel('Line voltages');
                    
                elseif strcmp(app.DropDown.Value,'Phase voltages')
                    plot(t,PV1,'r-');
                    hold on
                    plot(t,PV2,'b-');
                    hold on
                    plot(t,PV3,'g-');
                    legend('VP1','VP2','VP3')
                    xlabel('time');
                    ylabel('Phase voltages');
                    
                end
            end
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            app.freq=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Y-D.png';
            app.VanEditFieldLabel = uilabel(app.UIFigure);
            app.VanEditFieldLabel.Text = 'Van';
            app.VanEditField = uieditfield(app.UIFigure, 'text');
            app.VanEditField.ValueChangedFcn = createCallbackFcn(app, @VanEditFieldValueChanged, true);
            app.VbnEditFieldLabel = uilabel(app.UIFigure);
            app.VbnEditFieldLabel.Text = 'Vbn';
            app.VbnEditField = uieditfield(app.UIFigure, 'text');
            app.VbnEditField.ValueChangedFcn = createCallbackFcn(app, @VbnEditFieldValueChanged, true);
            app.VcnEditFieldLabel = uilabel(app.UIFigure);
            app.VcnEditFieldLabel.Text = 'Vcn';
            app.VcnEditField = uieditfield(app.UIFigure, 'text');
            app.VcnEditField.ValueChangedFcn = createCallbackFcn(app, @VcnEditFieldValueChanged, true);
            app.Line1EditFieldLabel = uilabel(app.UIFigure);
            app.Line1EditFieldLabel.Text = 'Line1';
            app.Line1EditField = uieditfield(app.UIFigure, 'text');
            app.Line1EditField.ValueChangedFcn = createCallbackFcn(app, @Line1EditFieldValueChanged, true);
            app.Line2EditFieldLabel = uilabel(app.UIFigure);
            app.Line2EditFieldLabel.Text = 'Line2';
            app.Line2EditField = uieditfield(app.UIFigure, 'text');
            app.Line2EditField.ValueChangedFcn = createCallbackFcn(app, @Line2EditFieldValueChanged, true);
            app.Line3EditFieldLabel = uilabel(app.UIFigure);
            app.Line3EditFieldLabel.Text = 'Line3';
            app.Line3EditField = uieditfield(app.UIFigure, 'text');
            app.Line3EditField.ValueChangedFcn = createCallbackFcn(app, @Line3EditFieldValueChanged, true);
            app.Load1EditFieldLabel = uilabel(app.UIFigure);
            app.Load1EditFieldLabel.Text = 'Load1';
            app.Load1EditField = uieditfield(app.UIFigure, 'text');
            app.Load1EditField.ValueChangedFcn = createCallbackFcn(app, @Load1EditFieldValueChanged, true);
            app.Load2EditFieldLabel = uilabel(app.UIFigure);
            app.Load2EditFieldLabel.Text = 'Load2';
            app.Load2EditField = uieditfield(app.UIFigure, 'text');
            app.Load2EditField.ValueChangedFcn = createCallbackFcn(app, @Load2EditFieldValueChanged, true);
            app.Load3EditFieldLabel = uilabel(app.UIFigure);
            app.Load3EditFieldLabel.Text = 'Load3';
            app.Load3EditField = uieditfield(app.UIFigure, 'text');
            app.Load3EditField.ValueChangedFcn = createCallbackFcn(app, @Load3EditFieldValueChanged, true);
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output Panel';
            app.LineCurrentsALabel = uilabel(app.OutputPanel);
            app.LineCurrentsALabel.Text = 'Line Currents(A)';
            app.PhaseCurrentsALabel = uilabel(app.OutputPanel);
            app.PhaseCurrentsALabel.Text = 'Phase Currents(A)';
            app.LineVoltagesVLabel = uilabel(app.OutputPanel);
            app.LineVoltagesVLabel.Text = 'Line Voltages(V)';
            app.PhaseVoltagesVLabel = uilabel(app.OutputPanel);
            app.PhaseVoltagesVLabel.Text = 'Phase Voltages(V)';
            app.TotalRealPowerLabel = uilabel(app.OutputPanel);
            app.TotalRealPowerLabel.Text = 'Total Real Power:';
            app.TotalRealPowerEditField = uieditfield(app.OutputPanel, 'numeric');
            app.TotalReactivePowerLabel = uilabel(app.OutputPanel);
            app.TotalReactivePowerLabel.Text = 'Total Reactive Power:';
            app.TotalReactivePowerEditField = uieditfield(app.OutputPanel, 'numeric');
            app.PowerFactorLabel = uilabel(app.OutputPanel);
            app.PowerFactorLabel.Text = 'Power Factor:';
            app.PowerFactorEditField = uieditfield(app.OutputPanel, 'numeric');
            app.WLabel = uilabel(app.OutputPanel);
            app.WLabel.Text = 'W';
            app.VARLabel = uilabel(app.OutputPanel);
            app.VARLabel.Text = 'VAR';
            app.VALabel = uilabel(app.OutputPanel);
            app.VALabel.Text = 'VA';
            app.I_aAEditFieldLabel = uilabel(app.OutputPanel);
            app.I_aAEditFieldLabel.Text = 'I_aA';
            app.I_aAEditField = uieditfield(app.OutputPanel, 'text');
            app.I_aAEditField.ValueChangedFcn = createCallbackFcn(app, @I_aAEditFieldValueChanged, true);
            app.I_bBEditFieldLabel = uilabel(app.OutputPanel);
            app.I_bBEditFieldLabel.Text = 'I_bB';
            app.I_bBEditField = uieditfield(app.OutputPanel, 'text');
            app.I_cCEditFieldLabel = uilabel(app.OutputPanel);
            app.I_cCEditFieldLabel.Text = 'I_cC';
            app.I_cCEditField = uieditfield(app.OutputPanel, 'text');
            app.I_ABEditFieldLabel = uilabel(app.OutputPanel);
            app.I_ABEditFieldLabel.Text = 'I_AB';
            app.I_ABEditField = uieditfield(app.OutputPanel, 'text');
            app.I_BCEditFieldLabel = uilabel(app.OutputPanel);
            app.I_BCEditFieldLabel.Text = 'I_BC';
            app.I_BCEditField = uieditfield(app.OutputPanel, 'text');
            app.I_CAEditFieldLabel = uilabel(app.OutputPanel);
            app.I_CAEditFieldLabel.Text = 'I_CA';
            app.I_CAEditField = uieditfield(app.OutputPanel, 'text');
            app.V_ABEditField_3Label = uilabel(app.OutputPanel);
            app.V_ABEditField_3Label.Text = 'V_AB';
            app.V_ABEditField_3 = uieditfield(app.OutputPanel, 'text');
            app.V_BCEditField_3Label = uilabel(app.OutputPanel);
            app.V_BCEditField_3Label.Text = 'V_BC';
            app.V_BCEditField_3 = uieditfield(app.OutputPanel, 'text');
            app.V_CAEditField_3Label = uilabel(app.OutputPanel);
            app.V_CAEditField_3Label.Text = 'V_CA';
            app.V_CAEditField_3 = uieditfield(app.OutputPanel, 'text');
            app.V_ABEditField_4Label = uilabel(app.OutputPanel);
            app.V_ABEditField_4Label.Text = 'V_AB';
            app.V_ABEditField_4 = uieditfield(app.OutputPanel, 'text');
            app.V_BCEditField_4Label = uilabel(app.OutputPanel);
            app.V_BCEditField_4Label.Text = 'V_BC';
            app.V_BCEditField_4 = uieditfield(app.OutputPanel, 'text');
            app.V_CAEditField_4Label = uilabel(app.OutputPanel);
            app.V_CAEditField_4Label.Text = 'V_CA';
            app.V_CAEditField_4 = uieditfield(app.OutputPanel, 'text');
            app.LeadorlagEditField = uieditfield(app.OutputPanel, 'text');
            app.AtLoadsEndLabel = uilabel(app.OutputPanel);
            app.AtLoadsEndLabel.Text = 'At Load''s End';
            app.TotalComplexPowerEditFieldLabel = uilabel(app.OutputPanel);
            app.TotalComplexPowerEditFieldLabel.Text = 'Total Complex Power';
            app.TotalComplexPowerEditField = uieditfield(app.OutputPanel, 'text');
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = 'C';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Text = 'c';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '+';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '+';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '-';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '-';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '-';
            app.Panel = uipanel(app.UIFigure);
            app.GiveinputsinthefigurebelowinaibformatLabel = uilabel(app.Panel);
            app.GiveinputsinthefigurebelowinaibformatLabel.Text = 'Give inputs in the figure below in a+ib format';
            app.SIMULATEButton = uibutton(app.Panel, 'push');
            app.SIMULATEButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULATEButtonPushed, true);
            app.SIMULATEButton.Text = 'SIMULATE';
            app.BACKButton = uibutton(app.Panel, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Line voltages', 'Phase voltages', 'Line currents', 'Phase currents'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Line voltages';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FrequencyEditFieldLabel = uilabel(app.Panel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.Panel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WYE_DELTA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end